classdef DL1_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SettingsMenu               matlab.ui.container.Menu
        TresholdValueSettingsMenu  matlab.ui.container.Menu
        NextDataSetButton          matlab.ui.control.Button
        EditField                  matlab.ui.control.EditField
        IndicatorPanel             matlab.ui.container.Panel
        Lamp_7                     matlab.ui.control.Lamp
        Lamp_6                     matlab.ui.control.Lamp
        Lamp_5                     matlab.ui.control.Lamp
        Lamp_4                     matlab.ui.control.Lamp
        LiveDataMonitoringPanel    matlab.ui.container.Panel
        Vis                        matlab.ui.control.NumericEditField
        obsmLabel                  matlab.ui.control.Label
        DVisEditField              matlab.ui.control.NumericEditField
        DVisEditFieldLabel         matlab.ui.control.Label
        DCOEditField               matlab.ui.control.NumericEditField
        DCOEditFieldLabel          matlab.ui.control.Label
        DTempEditField             matlab.ui.control.NumericEditField
        DTempEditFieldLabel        matlab.ui.control.Label
        CO                         matlab.ui.control.NumericEditField
        COppmLabel                 matlab.ui.control.Label
        Temp                       matlab.ui.control.NumericEditField
        Label                      matlab.ui.control.Label
        Location11DetectorSetsMonitorLabel  matlab.ui.control.Label
    end

    
properties (Access = private)
        SetThre1_1
        TemThre1_1
        COThre1_1
        VisThre1_1 
        t
        i
        callingapp
end
    
   methods (Access = public)
        
        function importTemp(app,name)
            app.DTempEditField.Value=name;
        end
    end

    methods (Access = public)
        
        function importCO(app,name)
            app.DCOEditField.Value=name;
        end
    end

    methods (Access = public)
        
        function importVis(app,name)
            app.DVisEditField.Value=name;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Station)
            % default threshold values
            app.DTempEditField.Value=54;
            app.DCOEditField.Value=500;
            app.DVisEditField.Value=2;
            % pretend to have live data from an on-site location(fake/test by creating a csv file)
            app.i.Value=1;
            app.t=readtable('D:\Undergrad_FJUT&TTU\2021Dr. Wu,Jing\Alarm\tunnelDataFile2-1.csv');
            app.Temp.Value=app.t.Temp(app.i.Value);
            app.CO.Value=app.t.CO(app.i.Value);
            app.Vis.Value=app.t.Vis(app.i.Value);

            app.callingapp=Station;
        end

        % Menu selected function: TresholdValueSettingsMenu
        function TresholdValueSettingsMenuSelected(app, event)
            app.SetThre1_1=SetThre1_1(app); % call set threshold app interface(Threshold only for 1_1 Location)
            
        end

        % Button pushed function: NextDataSetButton
        function NextDataSetButtonPushed(app, event)
            % next Temp,CO,Vis data from detector
            app.i.Value=app.i.Value+1;
            app.Temp.Value=app.t.Temp(app.i.Value);
            app.CO.Value=app.t.CO(app.i.Value);
            app.Vis.Value=app.t.Vis(app.i.Value);
            
            %Temperature
            app.TemThre1_1 = app.DTempEditField.Value;   

            if app.Temp.Value> (app.TemThre1_1)
                app.Lamp_5.Color=[1.00,0.44,0.11];
            elseif app.Temp.Value<0.7*(app.TemThre1_1)
                app.Lamp_5.Color=[0.00,1.00,0.00];
            else
                app.Lamp_5.Color=[1.00,1.00,0.00];
            end

          %CO concentration
          app.COThre1_1 = app.DCOEditField.Value;     
          if app.CO.Value > app.COThre1_1
                app.Lamp_6.Color=[1.00,0.44,0.11];
            elseif app.CO.Value < 0.7*(app.COThre1_1)
                app.Lamp_6.Color=[0.00,1.00,0.00];
            else
                app.Lamp_6.Color=[1.00,1.00,0.00];
            end

            % Visibilty
            app.VisThre1_1 = app.DVisEditField.Value;
            if app.Vis.Value > app.VisThre1_1
                app.Lamp_7.Color=[1.00,0.44,0.11];
            elseif app.Vis.Value<0.7*(app.VisThre1_1)
                app.Lamp_7.Color=[0.00,1.00,0.00];
            else
                app.Lamp_7.Color=[1.00,1.00,0.00];
            end
            
            % fire alarm
            if app.Vis.Value>app.VisThre1_1 && app.CO.Value>app.COThre1_1 && app.Temp.Value>app.TemThre1_1
               app.EditField.Value='Fire detected at location 1-1!'; 
               app.Lamp_4.Color=[1.00,0.00,0.00];
               import11(app.callingapp,app.Lamp_4.Color,'Location 1-1, 100m from NREL JingWu Lab','NREL JingWu Lab---DOE Huimin Research Center','NREL JingWu Lab');
               % lamp color
               importL11(app.callingapp,[0.00,1.00,0.00],[0.00,1.00,0.00],[0.00,1.00,0.00],[0.00,1.00,0.00],[0.00,1.00,0.00],[0.00,1.00,0.00],[0.00,1.00,0.00]);
               [d,fs]=audioread('Close Our Eyes_Chelsea Lankes.mp3');  % voice alarm with evacuation direction guides
               sound(d,fs);
               % fake camera view
               videoFReader = vision.VideoFileReader('maria.mp4');
               videoPlayer = vision.VideoPlayer;
                 while ~isDone(videoFReader)
                       videoFrame = videoFReader();
                       videoPlayer(videoFrame);
                       pause(0.1)
                   end
                      release(videoPlayer);
                      release(videoFReader);
            else
                app.EditField.Value='All is well!';
                app.Lamp_4.Color=[0.00,1.00,0.00];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SettingsMenu = uimenu(app.UIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.TresholdValueSettingsMenu = uimenu(app.SettingsMenu);
            app.TresholdValueSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @TresholdValueSettingsMenuSelected, true);
            app.TresholdValueSettingsMenu.Accelerator = 'T';
            app.TresholdValueSettingsMenu.Text = 'Treshold Value Settings';
            app.Location11DetectorSetsMonitorLabel = uilabel(app.UIFigure);
            app.Location11DetectorSetsMonitorLabel.Text = 'Location 1-1 Detector Sets Monitor';
            app.LiveDataMonitoringPanel = uipanel(app.UIFigure);
            app.LiveDataMonitoringPanel.Title = 'Live Data Monitoring';
            app.Label = uilabel(app.LiveDataMonitoringPanel);
            app.Label.Text = 'Temperature (℃)';
            app.Temp = uieditfield(app.LiveDataMonitoringPanel, 'numeric');
            app.Temp.Limits = [-20 500];
            app.COppmLabel = uilabel(app.LiveDataMonitoringPanel);
            app.COppmLabel.Text = 'CO Concentration (ppm)';
            app.CO = uieditfield(app.LiveDataMonitoringPanel, 'numeric');
            app.CO.Limits = [0 Inf];
            app.DTempEditFieldLabel = uilabel(app.LiveDataMonitoringPanel);
            app.DTempEditFieldLabel.Visible = 'off';
            app.DTempEditFieldLabel.Text = 'DTemp';
            app.DTempEditField = uieditfield(app.LiveDataMonitoringPanel, 'numeric');
            app.DTempEditField.Visible = 'off';
            app.DCOEditFieldLabel = uilabel(app.LiveDataMonitoringPanel);
            app.DCOEditFieldLabel.Visible = 'off';
            app.DCOEditFieldLabel.Text = 'DCO';
            app.DCOEditField = uieditfield(app.LiveDataMonitoringPanel, 'numeric');
            app.DCOEditField.Visible = 'off';
            app.DVisEditFieldLabel = uilabel(app.LiveDataMonitoringPanel);
            app.DVisEditFieldLabel.Visible = 'off';
            app.DVisEditFieldLabel.Text = 'DVis';
            app.DVisEditField = uieditfield(app.LiveDataMonitoringPanel, 'numeric');
            app.DVisEditField.Visible = 'off';
            app.obsmLabel = uilabel(app.LiveDataMonitoringPanel);
            app.obsmLabel.Text = 'Visibility (obs/m)';
            app.Vis = uieditfield(app.LiveDataMonitoringPanel, 'numeric');
            app.Lamp_4 = uilamp(app.UIFigure);
            app.IndicatorPanel = uipanel(app.UIFigure);
            app.IndicatorPanel.Title = 'Indicator';
            app.Lamp_5 = uilamp(app.IndicatorPanel);
            app.Lamp_6 = uilamp(app.IndicatorPanel);
            app.Lamp_7 = uilamp(app.IndicatorPanel);
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'All is well!';
            app.NextDataSetButton = uibutton(app.UIFigure, 'push');
            app.NextDataSetButton.ButtonPushedFcn = createCallbackFcn(app, @NextDataSetButtonPushed, true);
            app.NextDataSetButton.Text = 'Next Data Set';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DL1_1(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end