classdef Station < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TestMenu                      matlab.ui.container.Menu
        FireTestMenu                  matlab.ui.container.Menu
        NextButton                    matlab.ui.control.Button
        VentVelo                      matlab.ui.control.NumericEditField
        VentilationVelocitymsEditFieldLabel  matlab.ui.control.Label
        VentDirect                    matlab.ui.control.EditField
        VentilationDirectionEditFieldLabel  matlab.ui.control.Label
        FireLoca                      matlab.ui.control.EditField
        FireLocationEditFieldLabel    matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        Lamp_17                       matlab.ui.control.Lamp
        Lamp_16                       matlab.ui.control.Lamp
        Lamp_15                       matlab.ui.control.Lamp
        Lamp_14                       matlab.ui.control.Lamp
        Lamp_13                       matlab.ui.control.Lamp
        Lamp_12                       matlab.ui.control.Lamp
        Lamp_11                       matlab.ui.control.Lamp
        Lamp_10                       matlab.ui.control.Lamp
        Lamp_9                        matlab.ui.control.Lamp
        EvaDR                         matlab.ui.control.EditField
        EvacuationDirectionsEditFieldLabel  matlab.ui.control.Label
        TunnelSectionMonitoringPanel  matlab.ui.container.Panel
        DOEHuiminResearchCenterLabel  matlab.ui.control.Label
        NRELJingWuLabLabel            matlab.ui.control.Label
        Button_4                      matlab.ui.control.Button
        Button_3                      matlab.ui.control.Button
        Button_2                      matlab.ui.control.Button
        Button                        matlab.ui.control.Button
        Lamp_4                        matlab.ui.control.Lamp
        Lamp_3                        matlab.ui.control.Lamp
        Lamp_2                        matlab.ui.control.Lamp
        Lamp                          matlab.ui.control.Lamp
        Image                         matlab.ui.control.Image
    end

    
    properties (Access = private)
        DL11% Description
    end

    % export fire indicator color values from DL1_1 to Station
    methods (Access = public)
        
        function import11(app,name,name1,name2,name3)
            app.Lamp.Color=name;
            app.FireLoca.Value=name1;   % call fire location
            app.VentDirect.Value=name2;
            app.EvaDR.Value=name3;
        end

        function importL11(app,L9,L12,L13,L10,L15,L16,L11)
                app.Lamp_9.Color=L9; % direction indicator
                app.Lamp_12.Color=L12;
                app.Lamp_13.Color=L13;
                app.Lamp_10.Color=L10;
                app.Lamp_15.Color=L15;
                app.Lamp_16.Color=L16;
                app.Lamp_11.Color=L11;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.EvaDR.Value='All is well!';
            app.VentDirect.Value='All is well!';
            app.FireLoca.Value='All is well!';
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
             app.DL11=DL1_1(app); % open detector location1_1 display window
             
        end

        % Value changed function: FireLoca
        function FireLocaValueChanged(app, event)
%        FRloca=app.FireLoca.Value;
%         evaluate if detector values trigger fire alarm 
%       if strcmp(FRloca,'Location 1-4, 100m from DOE Huimin Research Center')
%                 app.EvaDR.Value='NREL JingWu Lab---DOE Huimin Research Center';
%                 app.Lamp_9.Color=[0.00,1.00,0.00]; % direction indicator 
%                 app.Lamp_12.Color=[0.00,1.00,0.00];
%                 app.Lamp_13.Color=[0.00,1.00,0.00];
%                 app.Lamp_14.Color=[0.00,1.00,0.00];
%                 app.Lamp_15.Color=[0.00,1.00,0.00];
%                 app.Lamp_16.Color=[0.00,1.00,0.00];
%                 app.Lamp_17.Color=[0.00,1.00,0.00];
%                 app.VentDirect.Value='DOE Huimin Research Center---NREL JingWu Lab'; % ventilation direction, opposite to evacuation direction
%       
%           elseif strcmp(FRloca,'Location 1-3, 200m from DOE Huimin Research Center')
%                 app.EvaDR.Value='NREL JingWu Lab---DOE Huimin Research Center';
%                 app.Lamp_9.Color=[0.00,1.00,0.00]; % direction indicator 
%                 app.Lamp_12.Color=[0.00,1.00,0.00];
%                 app.Lamp_13.Color=[0.00,1.00,0.00];
%                 app.Lamp_14.Color=[0.00,1.00,0.00];
%                 app.Lamp_15.Color=[0.00,1.00,0.00];
%                 app.Lamp_16.Color=[0.00,1.00,0.00];
%                 app.Lamp_17.Color=[0.00,1.00,0.00];
%                 app.VentDirect.Value='DOE Huimin Research Center---NREL JingWu Lab'; 
% 
%         elseif strcmp(FRloca,'Location 1-2, 200m from NREL JingWu Lab')
%                 app.EvaDR.Value='DOE Huimin Research Center---NREL JingWu Lab';
%                 app.Lamp_9.Color=[0.00,1.00,0.00]; % direction indicator
%                 app.Lamp_12.Color=[0.00,1.00,0.00];
%                 app.Lamp_13.Color=[0.00,1.00,0.00];
%                 app.Lamp_10.Color=[0.00,1.00,0.00];
%                 app.Lamp_15.Color=[0.00,1.00,0.00];
%                 app.Lamp_16.Color=[0.00,1.00,0.00];
%                 app.Lamp_11.Color=[0.00,1.00,0.00];
%                 app.VentDirect.Value='NREL JingWu Lab---DOE Huimin Research Center';
% 
%          elseif strcmp(FRloca,'Location 1-1, 100m from NREL JingWu Lab')
%                 app.EvaDR.Value='DOE Huimin Research Center---NREL JingWu Lab';
%                 app.Lamp_9.Color=[0.00,1.00,0.00]; % direction indicator
%                 app.Lamp_12.Color=[0.00,1.00,0.00];
%                 app.Lamp_13.Color=[0.00,1.00,0.00];
%                 app.Lamp_10.Color=[0.00,1.00,0.00];
%                 app.Lamp_15.Color=[0.00,1.00,0.00];
%                 app.Lamp_16.Color=[0.00,1.00,0.00];
%                 app.Lamp_11.Color=[0.00,1.00,0.00];
%                 app.VentDirect.Value='NREL JingWu Lab---DOE Huimin Research Center';
%              end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TestMenu = uimenu(app.UIFigure);
            app.TestMenu.Text = 'Test';
            app.FireTestMenu = uimenu(app.TestMenu);
            app.FireTestMenu.Accelerator = 'F';
            app.FireTestMenu.Text = 'Fire Test';
            app.TunnelSectionMonitoringPanel = uipanel(app.UIFigure);
            app.TunnelSectionMonitoringPanel.Title = 'Tunnel Section Monitoring';
            app.Image = uiimage(app.TunnelSectionMonitoringPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'icons', 'illustration.png');
            app.Lamp = uilamp(app.TunnelSectionMonitoringPanel);
            app.Lamp_2 = uilamp(app.TunnelSectionMonitoringPanel);
            app.Lamp_3 = uilamp(app.TunnelSectionMonitoringPanel);
            app.Lamp_4 = uilamp(app.TunnelSectionMonitoringPanel);
            app.Button = uibutton(app.TunnelSectionMonitoringPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '1-1 Live Data';
            app.Button_2 = uibutton(app.TunnelSectionMonitoringPanel, 'push');
            app.Button_2.Text = '1-2 Live Data';
            app.Button_3 = uibutton(app.TunnelSectionMonitoringPanel, 'push');
            app.Button_3.Text = '1-3 Live Data';
            app.Button_4 = uibutton(app.TunnelSectionMonitoringPanel, 'push');
            app.Button_4.Text = '1-4 Live Data';
            app.NRELJingWuLabLabel = uilabel(app.TunnelSectionMonitoringPanel);
            app.NRELJingWuLabLabel.Text = 'NREL JingWu Lab';
            app.DOEHuiminResearchCenterLabel = uilabel(app.TunnelSectionMonitoringPanel);
            app.DOEHuiminResearchCenterLabel.Text = 'DOE Huimin Research Center';
            app.EvacuationDirectionsEditFieldLabel = uilabel(app.UIFigure);
            app.EvacuationDirectionsEditFieldLabel.Text = 'Evacuation Directions';
            app.EvaDR = uieditfield(app.UIFigure, 'text');
            app.Panel = uipanel(app.UIFigure);
            app.Lamp_9 = uilamp(app.Panel);
            app.Lamp_10 = uilamp(app.Panel);
            app.Lamp_11 = uilamp(app.Panel);
            app.Lamp_12 = uilamp(app.Panel);
            app.Lamp_13 = uilamp(app.Panel);
            app.Lamp_14 = uilamp(app.Panel);
            app.Lamp_15 = uilamp(app.Panel);
            app.Lamp_16 = uilamp(app.Panel);
            app.Lamp_17 = uilamp(app.Panel);
            app.FireLocationEditFieldLabel = uilabel(app.UIFigure);
            app.FireLocationEditFieldLabel.Text = 'Fire Location';
            app.FireLoca = uieditfield(app.UIFigure, 'text');
            app.FireLoca.ValueChangedFcn = createCallbackFcn(app, @FireLocaValueChanged, true);
            app.VentilationDirectionEditFieldLabel = uilabel(app.UIFigure);
            app.VentilationDirectionEditFieldLabel.Text = 'Ventilation Direction';
            app.VentDirect = uieditfield(app.UIFigure, 'text');
            app.VentilationVelocitymsEditFieldLabel = uilabel(app.UIFigure);
            app.VentilationVelocitymsEditFieldLabel.Text = 'Ventilation Velocity (m/s)';
            app.VentVelo = uieditfield(app.UIFigure, 'numeric');
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.Text = 'Next';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Station

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end